classdef Final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel_7                         matlab.ui.container.Panel
        AmplitudRSTextArea_2            matlab.ui.control.TextArea
        AmplitudRSTextArea_2Label       matlab.ui.control.Label
        FrecuenciaCardiacaTextArea_2    matlab.ui.control.TextArea
        FrecuenciaCardiacaTextArea_2Label  matlab.ui.control.Label
        IntervaloPRTextArea_2           matlab.ui.control.TextArea
        IntervaloPRTextArea_2Label      matlab.ui.control.Label
        IntervaloQRSTextArea_2          matlab.ui.control.TextArea
        IntervaloQRSTextArea_2Label     matlab.ui.control.Label
        FrecuenciasIntermeciasTextArea_2  matlab.ui.control.TextArea
        GrabarButton_2                  matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        Tab_2                           matlab.ui.container.Tab
        FrecuenciaButton                matlab.ui.control.Button
        TiempoButton_2                  matlab.ui.control.Button
        SEALESFILTRADASLabel            matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        UIAxes3                         matlab.ui.control.UIAxes
        Tab2_2                          matlab.ui.container.Tab
        FrecuenciaButton_2              matlab.ui.control.Button
        TiempoButton_3                  matlab.ui.control.Button
        SEALESFILTRADASLabel_2          matlab.ui.control.Label
        Panel_4                         matlab.ui.container.Panel
        UIAxes4                         matlab.ui.control.UIAxes
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        TiempoButton                    matlab.ui.control.Button
        FaseButton                      matlab.ui.control.Button
        DecibeliosButton_2              matlab.ui.control.Button
        MagnitudButton                  matlab.ui.control.Button
        FILTROFIRLabel                  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        Tab2                            matlab.ui.container.Tab
        FaseButton_2                    matlab.ui.control.Button
        DecibeliosButton                matlab.ui.control.Button
        MagnitudButton_2                matlab.ui.control.Button
        FILTROIIRLabel                  matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        UIAxes2                         matlab.ui.control.UIAxes
        RESULTADOSIIRLabel              matlab.ui.control.Label
        RESULTADORFIRLabel              matlab.ui.control.Label
        Panel_6                         matlab.ui.container.Panel
        AmplitudRSTextArea              matlab.ui.control.TextArea
        AmplitudRSTextAreaLabel         matlab.ui.control.Label
        FrecuenciaCardiacaTextArea      matlab.ui.control.TextArea
        FrecuenciaCardiacaTextAreaLabel  matlab.ui.control.Label
        IntervaloPRTextArea             matlab.ui.control.TextArea
        IntervaloPRTextAreaLabel        matlab.ui.control.Label
        IntervaloQRSTextArea            matlab.ui.control.TextArea
        IntervaloQRSTextAreaLabel       matlab.ui.control.Label
        FrecuenciasIntermeciasTextArea  matlab.ui.control.TextArea
        GrabarButton                    matlab.ui.control.Button
        Panel_5                         matlab.ui.container.Panel
        SEALORIGINALLabel               matlab.ui.control.Label
        SubirArchivoButton              matlab.ui.control.Button
        UIAxes5                         matlab.ui.control.UIAxes
        Label                           matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SubirArchivoButton
        function SubirArchivoButtonPushed(app, event)
         global x Fs T n t ECG R_fir ampR_fir S_fir ampS_fir Q_fir ampQ_fir P_fir ampP_fir 
            
            cla(app.UIAxes,'reset');
            cla(app.UIAxes2,'reset');
            cla(app.UIAxes3,'reset');
            cla(app.UIAxes4,'reset');

            % Abrir el selector de archivos
            [nombreArchivo, rutaArchivo] = uigetfile('*.mat', 'Seleccionar archivo MAT');
            
            % Comprobar si se seleccionó un archivo
            if nombreArchivo ~= 0
                % Cargar el archivo MAT
                datos = load(fullfile(rutaArchivo, nombreArchivo));
                x = datos.x;
                Fs = 500;
                T = 1/Fs;
                n = 0:length(x)-1;
                t = n*T;
                
                cla(app.UIAxes5,'reset');
                plot(app.UIAxes5,t,x)
                title(app.UIAxes5,'Señal Original en el tiempo');
                xlabel(app.UIAxes5,'Tiempo (s)');
                ylabel(app.UIAxes5,'Amplitud (mV)');
            elseif isequal(nombreArchivo, 0) 
                 uialert(app.UIFigure, 'Se ha selecionado un archivo incorrecto', 'Invalid File', 'Icon', 'warning');
                 return
            end
        
            % N = 41;
            % Fc = 11;    %35Hz   Frecuencia de corte en hz
            % thetA = (Fc*2*pi)/Fc;   %frec de corte en rad
            % n_filtro = 0:N;    %numero de muestras 
            % 
            % %ventana de hanning
            % w = 0.54 - (0.46*cos((2*pi*n_filtro)/(N)));
            % 
            % %filtro prototipo N par impar
            % hb = (sin(thetA*(n_filtro-(N/2))))/(pi*(n_filtro-(N/2)));
            % %filtro desplazado
            % hnb = hb * w;

            %%------------------------Fir-----------------------------------------------------
            N =40;
            ventana = blackman(N+1);
            b = fir1(N,0.14,ventana,"low");
            
            %Filtro FIR en el tiempo
            [XFIR,FRECFIR] = freqz(b,1,8192,Fs);

            plot(app.UIAxes,FRECFIR,XFIR);
            title(app.UIAxes,'Respuesta en frecuencia del filtro FIR');
            xlabel(app.UIAxes,'Frecuencia (Hz)');
            ylabel(app.UIAxes,'Amplitud (mV)');
            
            %Señal filtrada
            x_fil = filtrar(x,b,1);
            %[YFIR,FREYFIR] = freqz(y1,1,8192,Fs);

            [p s mu] = polyfit(t,x_fil,9);
            tendencia = polyval(p,t,[],mu);
            
            ECG = x_fil-tendencia;

            [R_fir, ampR_fir, S_fir, ampS_fir, Q_fir, ampQ_fir, P_fir, ampP_fir] = PQRS(ECG,t,Fs);

            plot(app.UIAxes3,t,ECG);
            hold(app.UIAxes3)
            scatter(app.UIAxes3,P_fir,ECG(round(P_fir*Fs)));
            scatter(app.UIAxes3,Q_fir,ECG(round(Q_fir*Fs)));
            scatter(app.UIAxes3,R_fir,ECG(round(R_fir*Fs)));
            scatter(app.UIAxes3,S_fir,ECG(round(S_fir*Fs)));
            legend(app.UIAxes3,"ECG","P","Q","R","S");
            title(app.UIAxes3,'Señal Filtrada')
            xlabel(app.UIAxes3,'Tiempo (s)');
            ylabel(app.UIAxes3,'Amplitud (mV)');


            %Intervalos con FIR
            
            %Intervalo PR - FIR
            aux1=0;
            Pr = "";
            for i = 1:1:length(R_fir)
                PR_FIR(i) = Q_fir(i)-P_fir(i);
            
                if (PR_FIR(i)<0.12 && PR_FIR(i)>0.20)
                    aux1 = aux1 + 1;
                    Pr = sprintf('%s• El intervalo PR #/%i presenta un fallo\n', Pr,i);
                end
            end
            if (aux1 == 0)
                Pr = sprintf('%s• Ningun intervalo PR presenta fallas\n', Pr);
            end

            app.IntervaloPRTextArea.Value = Pr;
                
            %Intervalo QRS - FIR
            aux2=0;
            QRS_inter = "";
            for i = 1:1:length(R_fir)
                QRS_FIR(i) = S_fir(i)-Q_fir(i);

                if (QRS_FIR(i)<0.06 && QRS_FIR(i)>0.12)
                    aux2 = aux2 + 1;
                    QRS_inter = sprintf('%s• El intervalo QRS #/%i presenta un fallo\n', QRS_inter,i);
                end
            end
            if (aux2 == 0)
                QRS_inter = sprintf('%s• Ningun intervalo QRS presenta fallas\n', QRS_inter);
            end 
            app.IntervaloQRSTextArea.Value = QRS_inter;

            %Frecuencias instantáneas - FIR
            aux3 = "";
            aux3 = sprintf('%s• Las Frecuencias Instantaneas son:\n', aux3);
            Tins = zeros(1,length(R_fir)-1);
            Fins = zeros(1,length(R_fir)-1);

            for i = 1:(length(R_fir)-1)
                Tins(i)=R_fir(i+1)-R_fir(i);
                Fins(i)=round(Tins(i)*60);
                aux3= sprintf("%s\t #%2d %2d bmp \n",aux3,i,Fins(i));
            end
            app.FrecuenciasIntermeciasTextArea.Value = aux3;

            %Frecuencia cardiaca - FIR
            TCar = R_fir(end)-R_fir(1);
            FCar = round(((length(R_fir)-1)/TCar)*60);
            aux4 = "";
            if FCar < 60
                aux4 = sprintf("%s• El paciente tiene bradicardia (%d bmp)\n",aux4,FCar);
            elseif (FCar >= 60 && FCar < 95)
                aux4 = sprintf("%s• El paciente tiene un ritmo cardiaco normal (%d bmp)\n",aux4,FCar);
            else
                aux4 = sprintf("%s• El paciente tiene taquicardia (%d bmp)\n",aux4,FCar);
            end
            app.FrecuenciaCardiacaTextArea.Value = aux4;

            %Diferencia Amplitud RS - FIR
            aux5 = "";
            aux5 = sprintf('%s• Diferencia Amplitud RS:\n', aux5);
            for i = 1:(length(R_fir)-1)
                dif1 = ampR_fir(i) - ampS_fir(i);
                dif2 = ampR_fir(i+1) - ampS_fir(i+1);
                dif = dif2 - dif1;
                aux5 = sprintf("%s\t #%2d %.2f mV \n",aux5,i,dif);
            end
            app.AmplitudRSTextArea.Value = aux5;


            %%------------------------------------IIR---------------------------------------------

            %%Filtrado de la señal IIR
            Fc = 35;     %Frecuencia de corte en Hz
            
            wcn = Fc/(Fs/2);
            [bute,an] = butter(15,wcn,'low');
            
            x_fil2 = filtrar(x,bute,an);
            
            [XIIR,FRECIIR] = freqz(bute,an,8192,Fs);

            plot(app.UIAxes2,FRECIIR,XIIR);
            title(app.UIAxes2,'Respuesta en frecuencia del filtro IIR');
            xlabel(app.UIAxes2,'Frecuencia (Hz)');
            ylabel(app.UIAxes2,'Amplitud (mV)');
            
            [p2 s2 mu2] = polyfit(t,x_fil2,9);
            tendencia = polyval(p2,t,[],mu2);
            
            %Señal filtrada
            ECG2 = x_fil2-tendencia;
            
            [R_iir, ampR_iir, S_iir, ampS_iir, Q_iir, ampQ_iir, P_iir, ampP_iir] = PQRS(ECG2,t,Fs);

            plot(app.UIAxes4,t,ECG);
            hold(app.UIAxes4)
            scatter(app.UIAxes4,P_iir,ECG2(round(P_iir*Fs)));
            scatter(app.UIAxes4,Q_iir,ECG2(round(Q_iir*Fs)));
            scatter(app.UIAxes4,R_iir,ECG2(round(R_iir*Fs)));
            scatter(app.UIAxes4,S_iir,ECG2(round(S_iir*Fs)));
            legend(app.UIAxes4,"ECG","P","Q","R","S");
            title(app.UIAxes4,'Señal Filtrada')
            xlabel(app.UIAxes4,'Tiempo (s)');
            ylabel(app.UIAxes4,'Amplitud (mV)');

            %Intervalos con IIR
            
            %Intervalo PR - IIR
            aux1IR=0;
            PrIIR = "";
            for i = 1:1:length(R_iir)
                PR_IIR(i) = Q_iir(i)-P_iir(i);
            
                if (PR_IIR(i)<0.12 && PR_IIR(i)>0.20)
                    aux1IR = aux1IR + 1;
                    PrIIR = sprintf('%s• El intervalo PR #/%i presenta un fallo\n', PrIIR,i);
                end
            end
            if (aux1IR == 0)
                PrIIR = sprintf('%s• Ningun intervalo PR presenta fallas\n', PrIIR);
            end

            app.IntervaloPRTextArea_2.Value = PrIIR;
                

            %Intervalo QRS - IIR
            aux2IR=0;
            QRS_interIR = "";
            for i = 1:1:length(R_iir)
                QRS_IR(i) = S_iir(i)-Q_iir(i);

                if (QRS_IR(i)<0.06 && QRS_IR(i)>0.12)
                    aux2IR = aux2IR + 1;
                    QRS_interIR = sprintf('%s• El intervalo QRS #/%i presenta un fallo\n', QRS_interIR,i);
                end
            end
            if (aux2IR == 0)
                QRS_interIR = sprintf('%s• Ningun intervalo QRS presenta fallas\n', QRS_interIR);
            end 
            app.IntervaloQRSTextArea_2.Value = QRS_interIR;
            

            %Frecuencias instantáneas - IIR
            aux3IR = "";
            aux3IR = sprintf('%s• Las Frecuencias Instantaneas son:\n', aux3IR);
            TinsIR = zeros(1,length(R_iir)-1);
            FinsIR = zeros(1,length(R_iir)-1);

            for i = 1:(length(R_iir)-1)
                TinsIR(i)=R_iir(i+1)-R_iir(i);
                FinsIR(i)=round(TinsIR(i)*60);
                aux3IR= sprintf("%s\t #%2d %2d bmp \n",aux3IR,i,FinsIR(i));
            end
            app.FrecuenciasIntermeciasTextArea_2.Value = aux3IR;

            %Frecuencia cardiaca - IIR
            TCarIR = R_iir(end)-R_iir(1);
            FCarIR = round(((length(R_iir)-1)/TCarIR)*60);
            aux4IR = "";
            if FCarIR < 60
                aux4IR = sprintf("%s• El paciente tiene bradicardia (%d bmp)\n",aux4IR,FCarIR);
            elseif (FCarIR >= 60 && FCarIR < 95)
                aux4IR = sprintf("%s• El paciente tiene un ritmo cardiaco normal (%d bmp)\n",aux4IR,FCarIR);
            else
                aux4IR = sprintf("%s• El paciente tiene taquicardia (%d bmp)\n",aux4IR,FCarIR);
            end
            app.FrecuenciaCardiacaTextArea_2.Value = aux4IR;

            %Diferencia Amplitud RS - IIR
            aux5IR = "";
            aux5IR = sprintf('%s• Diferencia Amplitud RS:\n', aux5IR);
            for i = 1:(length(R_iir)-1)
                dif1IR = ampR_iir(i) - ampS_iir(i);
                dif2IR = ampR_iir(i+1) - ampS_iir(i+1);
                difIR = dif2IR - dif1IR;
                aux5IR = sprintf("%s\t #%2d %.2f mV \n",aux5IR,i,difIR);
            end
            app.AmplitudRSTextArea_2.Value = aux5IR;


        end

        % Button pushed function: GrabarButton
        function GrabarButtonPushed(app, event)
            [Nombre, Ruta] = uiputfile('*.xlsx', 'Guardar como');

            % Comprobar si el usuario canceló la selección
            if isequal(Nombre, 0) || isequal(Ruta, 0)
                return; % Salir sin hacer nada si se cancela la selección
            end
        
            % Obtener el contenido del TextArea
            IntPR = app.IntervaloPRTextArea.Value;
            FrecCard = app.FrecuenciaCardiacaTextArea.Value;
            AmpRS = app.AmplitudRSTextArea.Value;
            IntQRS = app.IntervaloQRSTextArea.Value;
            FrecIM = app.FrecuenciasIntermeciasTextArea.Value;
            

            % Crear un nuevo libro de Excel
            fullPath = fullfile(Ruta, Nombre);
            excelFile = fullfile(fullPath);

            % Escribir el contenido en el archivo de Excel
            writecell(IntPR, excelFile,'Sheet', 1, 'Range', 'B3');
            writecell(FrecCard, excelFile,'Sheet', 1, 'Range', 'B4');
            writecell(AmpRS, excelFile,'Sheet', 1, 'Range', 'B5');
            writecell(IntQRS, excelFile,'Sheet', 1, 'Range', 'B6');
            writecell(FrecIM, excelFile,'Sheet', 1, 'Range', 'B7');
            

            % Mostrar un mensaje de éxito
            uialert(app.UIFigure, 'La información se ha guardado en el archivo de Excel.', 'Éxito', 'Icon', 'success');


        end

        % Button pushed function: GrabarButton_2
        function GrabarButton_2Pushed(app, event)
            [Nombre, Ruta] = uiputfile('*.xlsx', 'Guardar como');

            % Comprobar si el usuario canceló la selección
            if isequal(Nombre, 0) || isequal(Ruta, 0)
                return; % Salir sin hacer nada si se cancela la selección
            end
        
            % Obtener el contenido del TextArea
            IntPR = app.IntervaloPRTextArea_2.Value;
            FrecCard = app.FrecuenciaCardiacaTextArea_2.Value;
            AmpRS = app.AmplitudRSTextArea_2.Value;
            IntQRS = app.IntervaloQRSTextArea_2.Value;
            FrecIM = app.FrecuenciasIntermeciasTextArea_2.Value;
            

            % Crear un nuevo libro de Excel
            fullPath = fullfile(Ruta, Nombre);
            excelFile = fullfile(fullPath);

            % Escribir el contenido en el archivo de Excel
            writecell(IntPR, excelFile,'Sheet', 1, 'Range', 'B3');
            writecell(FrecCard, excelFile,'Sheet', 1, 'Range', 'B4');
            writecell(AmpRS, excelFile,'Sheet', 1, 'Range', 'B5');
            writecell(IntQRS, excelFile,'Sheet', 1, 'Range', 'B6');
            writecell(FrecIM, excelFile,'Sheet', 1, 'Range', 'B7');
            

            % Mostrar un mensaje de éxito
            uialert(app.UIFigure, 'La información se ha guardado en el archivo de Excel.', 'Éxito', 'Icon', 'success');
      

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'PROCESAMIENTO DIGITAL DE SEÑALES EN LA DETECCIÓN DE FALLAS DEL COMPLEJO QRS';
            app.Panel_5 = uipanel(app.UIFigure);
            app.UIAxes5 = uiaxes(app.Panel_5);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.SubirArchivoButton = uibutton(app.Panel_5, 'push');
            app.SubirArchivoButton.ButtonPushedFcn = createCallbackFcn(app, @SubirArchivoButtonPushed, true);
            app.SubirArchivoButton.Text = 'Subir Archivo';
            app.SEALORIGINALLabel = uilabel(app.Panel_5);
            app.SEALORIGINALLabel.Text = 'SEÑAL ORIGINAL';
            app.Panel_6 = uipanel(app.UIFigure);
            app.GrabarButton = uibutton(app.Panel_6, 'push');
            app.GrabarButton.ButtonPushedFcn = createCallbackFcn(app, @GrabarButtonPushed, true);
            app.GrabarButton.Text = 'Grabar';
            app.FrecuenciasIntermeciasTextArea = uitextarea(app.Panel_6);
            app.IntervaloQRSTextAreaLabel = uilabel(app.Panel_6);
            app.IntervaloQRSTextAreaLabel.Text = 'Intervalo QRS';
            app.IntervaloQRSTextArea = uitextarea(app.Panel_6);
            app.IntervaloPRTextAreaLabel = uilabel(app.Panel_6);
            app.IntervaloPRTextAreaLabel.Text = 'Intervalo PR';
            app.IntervaloPRTextArea = uitextarea(app.Panel_6);
            app.FrecuenciaCardiacaTextAreaLabel = uilabel(app.Panel_6);
            app.FrecuenciaCardiacaTextAreaLabel.Text = 'Frecuencia Cardiaca';
            app.FrecuenciaCardiacaTextArea = uitextarea(app.Panel_6);
            app.AmplitudRSTextAreaLabel = uilabel(app.Panel_6);
            app.AmplitudRSTextAreaLabel.Text = 'Amplitud RS';
            app.AmplitudRSTextArea = uitextarea(app.Panel_6);
            app.RESULTADORFIRLabel = uilabel(app.UIFigure);
            app.RESULTADORFIRLabel.Text = 'RESULTADOR FIR';
            app.RESULTADOSIIRLabel = uilabel(app.UIFigure);
            app.RESULTADOSIIRLabel.Text = 'RESULTADOS IIR';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Panel = uipanel(app.Tab);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.FILTROFIRLabel = uilabel(app.Tab);
            app.FILTROFIRLabel.Text = 'FILTRO FIR';
            app.MagnitudButton = uibutton(app.Tab, 'push');
            app.MagnitudButton.Text = 'Magnitud';
            app.DecibeliosButton_2 = uibutton(app.Tab, 'push');
            app.DecibeliosButton_2.Text = 'Decibelios';
            app.FaseButton = uibutton(app.Tab, 'push');
            app.FaseButton.Text = 'Fase';
            app.TiempoButton = uibutton(app.Tab, 'push');
            app.TiempoButton.Text = 'Tiempo';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.Panel_2 = uipanel(app.Tab2);
            app.UIAxes2 = uiaxes(app.Panel_2);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.FILTROIIRLabel = uilabel(app.Tab2);
            app.FILTROIIRLabel.Text = 'FILTRO IIR';
            app.MagnitudButton_2 = uibutton(app.Tab2, 'push');
            app.MagnitudButton_2.Text = 'Magnitud';
            app.DecibeliosButton = uibutton(app.Tab2, 'push');
            app.DecibeliosButton.Text = 'Decibelios';
            app.FaseButton_2 = uibutton(app.Tab2, 'push');
            app.FaseButton_2.Text = 'Fase';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.Tab_2 = uitab(app.TabGroup2);
            app.Tab_2.Title = 'Tab';
            app.Panel_3 = uipanel(app.Tab_2);
            app.UIAxes3 = uiaxes(app.Panel_3);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.SEALESFILTRADASLabel = uilabel(app.Tab_2);
            app.SEALESFILTRADASLabel.Text = 'SEÑALES FILTRADAS';
            app.TiempoButton_2 = uibutton(app.Tab_2, 'push');
            app.TiempoButton_2.Text = 'Tiempo';
            app.FrecuenciaButton = uibutton(app.Tab_2, 'push');
            app.FrecuenciaButton.Text = 'Frecuencia';
            app.Tab2_2 = uitab(app.TabGroup2);
            app.Tab2_2.Title = 'Tab2';
            app.Panel_4 = uipanel(app.Tab2_2);
            app.UIAxes4 = uiaxes(app.Panel_4);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.SEALESFILTRADASLabel_2 = uilabel(app.Tab2_2);
            app.SEALESFILTRADASLabel_2.Text = 'SEÑALES FILTRADAS';
            app.TiempoButton_3 = uibutton(app.Tab2_2, 'push');
            app.TiempoButton_3.Text = 'Tiempo';
            app.FrecuenciaButton_2 = uibutton(app.Tab2_2, 'push');
            app.FrecuenciaButton_2.Text = 'Frecuencia';
            app.Panel_7 = uipanel(app.UIFigure);
            app.GrabarButton_2 = uibutton(app.Panel_7, 'push');
            app.GrabarButton_2.ButtonPushedFcn = createCallbackFcn(app, @GrabarButton_2Pushed, true);
            app.GrabarButton_2.Text = 'Grabar';
            app.FrecuenciasIntermeciasTextArea_2 = uitextarea(app.Panel_7);
            app.IntervaloQRSTextArea_2Label = uilabel(app.Panel_7);
            app.IntervaloQRSTextArea_2Label.Text = 'Intervalo QRS';
            app.IntervaloQRSTextArea_2 = uitextarea(app.Panel_7);
            app.IntervaloPRTextArea_2Label = uilabel(app.Panel_7);
            app.IntervaloPRTextArea_2Label.Text = 'Intervalo PR';
            app.IntervaloPRTextArea_2 = uitextarea(app.Panel_7);
            app.FrecuenciaCardiacaTextArea_2Label = uilabel(app.Panel_7);
            app.FrecuenciaCardiacaTextArea_2Label.Text = 'Frecuencia Cardiaca';
            app.FrecuenciaCardiacaTextArea_2 = uitextarea(app.Panel_7);
            app.AmplitudRSTextArea_2Label = uilabel(app.Panel_7);
            app.AmplitudRSTextArea_2Label.Text = 'Amplitud RS';
            app.AmplitudRSTextArea_2 = uitextarea(app.Panel_7);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end